classdef FHRMA_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FHRMAmenuUIFigure            matlab.ui.Figure
        MadebySamuelBoudetCatholicUniversityofLilleFranceLabel  matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        FetalHeartRateMorphologicalAnalysisToolboxFHRMAv21Label  matlab.ui.control.Label
        MorphologicalAnalysisbaselineaccelerationdecelerationPanel  matlab.ui.container.Panel
        Button_19                    matlab.ui.control.Button
        Button_18                    matlab.ui.control.Button
        Button_17                    matlab.ui.control.Button
        FalseSignalFSDetectionPanel  matlab.ui.container.Panel
        Button_16                    matlab.ui.control.Button
        Button_15                    matlab.ui.control.Button
        Button_14                    matlab.ui.control.Button
        Button_13                    matlab.ui.control.Button
        CompleteprocessFalsesignaldetectionMorphologicalanalysisPanel  matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        OpenafileandseefullautomatedanalysiscommandFHRMAButton  matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            waiter=uiprogressdlg(app.FHRMAmenuUIFigure,'Message','Processing file','Indeterminate','on');
            drawnow
            FSDopGUI();
            close(waiter)
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            waiter=uiprogressdlg(app.FHRMAmenuUIFigure,'Message','Processing file','Indeterminate','on');
            drawnow
            FSScalpGUI();
            close(waiter)
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            waiter=uiprogressdlg(app.FHRMAmenuUIFigure,'Message','Wait during computation. This should take more or less 1 minute','Indeterminate','on');
            drawnow
            load('FHRMAdataset/analyses/expertAnalyses.mat','data')
            evaluateaam('aamwmfb(FHR)',data([data(:).trainingData]==1),'FHRMAdataset/traindata/');
            close(waiter)
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            fhrmorpho();
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            waiter=uiprogressdlg(app.FHRMAmenuUIFigure,'Message','Wait during computation. This should take several minutes','Indeterminate','on');
            drawnow
            EvalFSAllDatasets();
            close(waiter)
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            waiter=uiprogressdlg(app.FHRMAmenuUIFigure,'Message',sprintf('Wait during computation. This should take a few minutes. \nOnce finished the results on train dataset will be displayed for checking.\n If you want the evaluation on the test dataset, send the generated file (myMACustomMethod.mat) to samuel.boudet@univ-catholille.fr.\n'),'Indeterminate','on');
            drawnow
            saveMAanalyse;
            close(waiter)
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            waiter=uiprogressdlg(app.FHRMAmenuUIFigure,'Message',sprintf('Wait during computation. This should take several minutes. \nOnce finished the results on train dataset will be displayed for checking. \n If you want the evaluation on the test dataset, send the generated file (MyFSDopMethodAnalysis.mat) to samuel.boudet@univ-catholille.fr.\n'),'Indeterminate','on');
            drawnow
            SaveFSAndEvalCustomModel
            close(waiter)
        end

        % Button pushed function: 
        % OpenafileandseefullautomatedanalysiscommandFHRMAButton
        function OpenafileandseefullautomatedanalysiscommandFHRMAButtonPushed(app, event)
            FHRMA
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FHRMAmenuUIFigure = uifigure('Visible', 'off');
            app.FHRMAmenuUIFigure.Name = 'FHRMA menu';
            app.CompleteprocessFalsesignaldetectionMorphologicalanalysisPanel = uipanel(app.FHRMAmenuUIFigure);
            app.CompleteprocessFalsesignaldetectionMorphologicalanalysisPanel.Title = 'Complete process (False signal detection + Morphological analysis)';
            app.OpenafileandseefullautomatedanalysiscommandFHRMAButton = uibutton(app.CompleteprocessFalsesignaldetectionMorphologicalanalysisPanel, 'push');
            app.OpenafileandseefullautomatedanalysiscommandFHRMAButton.ButtonPushedFcn = createCallbackFcn(app, @OpenafileandseefullautomatedanalysiscommandFHRMAButtonPushed, true);
            app.OpenafileandseefullautomatedanalysiscommandFHRMAButton.Text = {'Open a file and see full automated analysis'; '(command FHRMA)'};
            app.Label = uilabel(app.CompleteprocessFalsesignaldetectionMorphologicalanalysisPanel);
            app.Label.Interpreter = 'html';
            app.Label.Text = 'You can also try on <a href="https://physionet.org/content/ctu-uhb-ctgdb/1.0.0/">CTU-UHB dataset</a> but since MHR sensor<br> is not recorded, false signal detection may be innacurate.';
            app.FalseSignalFSDetectionPanel = uipanel(app.FHRMAmenuUIFigure);
            app.FalseSignalFSDetectionPanel.Title = 'False Signal (FS) Detection';
            app.Button_13 = uibutton(app.FalseSignalFSDetectionPanel, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = {'See results of the Doppler/MHR '; 'False Signal analysis on the dedicated'; 'dataset (command : FSDopGUI)'};
            app.Button_14 = uibutton(app.FalseSignalFSDetectionPanel, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = {'See results of the Scalp ECG'; 'False Signal analysis on the dedicated'; 'dataset (command : FSScalpGUI)'};
            app.Button_15 = uibutton(app.FalseSignalFSDetectionPanel, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = {'Statistical Analysis '; 'on train/validation dataset '; '(Command : EvalFSAllDatasets)'};
            app.Button_16 = uibutton(app.FalseSignalFSDetectionPanel, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = {'Example of function to process false signals detection on all rec.'; ' of test (and train) datasets by a method (e.g. here FSDop) and '; 'save the results  in good format for evaluation '; '(Command SaveFSAndEvalCustomModel)'};
            app.MorphologicalAnalysisbaselineaccelerationdecelerationPanel = uipanel(app.FHRMAmenuUIFigure);
            app.MorphologicalAnalysisbaselineaccelerationdecelerationPanel.Title = 'Morphological Analysis (MA) (i.e. baseline, accelerations and decelerations)';
            app.Button_17 = uibutton(app.MorphologicalAnalysisbaselineaccelerationdecelerationPanel, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = {'See the Morphological Analysis'; 'of various methods on the dedicated '; 'dataset (command : fhrmorpho)'};
            app.Button_18 = uibutton(app.MorphologicalAnalysisbaselineaccelerationdecelerationPanel, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = {'Statistical evaluation of WMFB method on'; 'the train dataset (command : evaluateaam)'};
            app.Button_19 = uibutton(app.MorphologicalAnalysisbaselineaccelerationdecelerationPanel, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = {'Example of function to process morphological analysis on all rec. '; 'of test  (and train) datasets by a method (e.g. here WMFB) and '; 'save the results in good format for evaluation '; '(Command saveMAanalyse)'};
            app.FetalHeartRateMorphologicalAnalysisToolboxFHRMAv21Label = uilabel(app.FHRMAmenuUIFigure);
            app.FetalHeartRateMorphologicalAnalysisToolboxFHRMAv21Label.Text = 'Fetal Heart Rate Morphological Analysis Toolbox (FHRMA v2.1)';
            app.Label_2 = uilabel(app.FHRMAmenuUIFigure);
            app.Label_2.Interpreter = 'html';
            app.Label_2.Text = {'If you use any of those ressources (analysis method, display interface, datasets, methodology) '; 'in a published work, please <a href=''https://github.com/utsb-fmm/FHRMA''>check the citation rules</a>.'};
            app.MadebySamuelBoudetCatholicUniversityofLilleFranceLabel = uilabel(app.FHRMAmenuUIFigure);
            app.MadebySamuelBoudetCatholicUniversityofLilleFranceLabel.Text = 'Made by Biomedical Signal Processing Unit (UTSB) of Catholic University of Lille (France)';
            % Show the figure after all components are created
            app.FHRMAmenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FHRMA_menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FHRMAmenuUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FHRMAmenuUIFigure)
        end
    end
end